classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Label                matlab.ui.control.Label
        IDEditField          matlab.ui.control.NumericEditField
        IDEditFieldLabel     matlab.ui.control.Label
        punto7DropDown       matlab.ui.control.DropDown
        punto7DropDownLabel  matlab.ui.control.Label
        punto4DropDown       matlab.ui.control.DropDown
        punto4DropDownLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data=readtable('heart_data.csv');
save('heartdata.mat','data');
load("heartdata.mat");
%Edad de años a días
data.age=data.age/365
%Variables a Boleano
data.smoke=categorical(data.smoke,[0,1],{'Si','No'})
data.alco=categorical(data.alco,[0,1],{'Si','No'})
data.active=categorical(data.active,[0,1],{'Si','No'})
data.cardio=categorical(data.cardio,[0,1],{'Si','No'})


%Formula de el IMC
data.index = data.weight ./ (data.height.^2);
%Clasificacion segun los estándares de la Organización Mundial de la Salud
clasificacion = cell(size(data.index));
clasificacion(data.index < 18.5) = {'Bajo peso'};
clasificacion(data.index >= 18.5 & data.index < 25) = {'Normal'};
clasificacion(data.index>= 25 & data.index< 30) = {'Sobrepeso'};
clasificacion(data.index >= 30) = {'Obeso'};
presion=cell(size(data))
Presion = (data.ap_hi + 2*data.ap_lo)/3
        end

        % Value changed function: punto7DropDown
        function punto7DropDownValueChanged(app, event)
            data = readtable('heart_data.csv') 
            value = app.punto7DropDown.Value;
            cantidadDatos = 70000
            sumaCoresterol = 0

            if data.cardio == 1
                for i = 0: cantidadDatos
                    sumaCoresterol =+  data.cholesterol;

                end
            else

            end
        end

        % Value changed function: punto4DropDown
        function punto4DropDownValueChanged(app, event)
            value = app.punto4DropDown.Value;
           data = readtable('heart_data.csv');
         if value =="Punto 5"
                genero_columna = data.gender;
                genero_celda = table2cell(data);
                vector_F = genero_celda(strcmp(genero_celda, '1'));
                vector_M = genero_celda(strcmp(genero_celda, '2'));
                xlabel(app.UIAxes,'Generos');
                ylabel(app.UIAxes,'Cantidad');
                title(app.UIAxes,'Punto 5-Grafica de barras');
                xticklabels(app.UIAxes,{'F', 'M'});
                cantidad_F= length(vector_F);
                cantidad_M= length(vector_M);
                y= [cantidad_F,cantidad_M];
                bar (app.UIAxes,y);
            elseif value == "Punto 6" 
                IMC= data.index
                Colesterol = data.cholesterol
                Enfermedad_cardiovascular = data.cardio
                 
        % Separar los datos en dos grupos: con y sin enfermedades cardiovasculares
        conEnfermedad = (Enfermedad_cardiovascular == 1);
        sinEnfermedad = ~conEnfermedad;
         xlabel(app.UIAxes,'IMC');
         ylabel(app.UIAxes,'Colesterol');
         title(app.UIAxes,'Punto 6-Grafica de dispersión');
         columna = data.cardio
         celda= table2cell(data(:,"cardio"))
         xz = length(columna)
         yz = length(celda)
         
         scatter (app.UIAxes,xz,yz)
        
           
end
        end

        % Value changed function: IDEditField
        function IDEditFieldValueChanged(app, event)
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.punto4DropDownLabel = uilabel(app.UIFigure);
            app.punto4DropDownLabel.Text = 'punto 4';
            app.punto4DropDown = uidropdown(app.UIFigure);
            app.punto4DropDown.Items = {'Seleccionar', 'Punto 5 ', 'Punto 6'};
            app.punto4DropDown.ValueChangedFcn = createCallbackFcn(app, @punto4DropDownValueChanged, true);
            app.punto4DropDown.Value = 'Seleccionar';
            app.punto7DropDownLabel = uilabel(app.UIFigure);
            app.punto7DropDownLabel.Text = 'punto 7';
            app.punto7DropDown = uidropdown(app.UIFigure);
            app.punto7DropDown.Items = {'Desviacion  1', 'Media 2'};
            app.punto7DropDown.ValueChangedFcn = createCallbackFcn(app, @punto7DropDownValueChanged, true);
            app.punto7DropDown.Value = 'Desviacion  1';
            app.IDEditFieldLabel = uilabel(app.UIFigure);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.UIFigure, 'numeric');
            app.IDEditField.ValueChangedFcn = createCallbackFcn(app, @IDEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Label.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end